classdef NLX_Session2Neuro_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        seegLab                matlab.ui.control.Table
        SPpanel                matlab.ui.container.Panel
        expNWB                 matlab.ui.control.Button
        remapSEEG              matlab.ui.control.Button
        step3                  matlab.ui.control.Label
        step2                  matlab.ui.control.Label
        step1                  matlab.ui.control.Label
        findNEURO              matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        DLpanel                matlab.ui.container.Panel
        confirm                matlab.ui.control.Button
        sessLoc                matlab.ui.control.EditField
        SESSIONEditFieldLabel  matlab.ui.control.Label
        eventLoc               matlab.ui.control.EditField
        EVENTSEditFieldLabel   matlab.ui.control.Label
        FOpanel                matlab.ui.container.Panel
        fileNext               matlab.ui.control.Button
        fileLoad               matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainLoc % Description
        sessFILE % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: fileLoad
        function fileLoadButtonPushed(app, event)
            app.mainLoc = uigetdir();
            
            cd(app.mainLoc)
            
            app.sessFILE = uigetfile('*.mat');
            
            load(app.sessFILE,"sessionINFo")
            
            app.sessLoc.Value = sessionINFo.sessiLOC;
            app.eventLoc.Value = sessionINFo.eventLOC;
            
            app.confirm.Enable = 'on';
            
        end

        % Button pushed function: confirm
        function confirmButtonPushed(app, event)
            
            app.sessLoc.Editable = 'off';
            app.eventLoc.Editable = 'off';
            app.confirm.Enable = 'off';
            
            % Load in first panel


            % Activate Session Panel
            app.SPpanel.Enable = 'on';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FOpanel = uipanel(app.UIFigure);
            app.FOpanel.Title = 'FILE OPTIONS';
            app.fileLoad = uibutton(app.FOpanel, 'push');
            app.fileLoad.ButtonPushedFcn = createCallbackFcn(app, @fileLoadButtonPushed, true);
            app.fileLoad.Text = {'Load Files'; ''};
            app.fileNext = uibutton(app.FOpanel, 'push');
            app.fileNext.Enable = 'off';
            app.fileNext.Text = 'Next File';
            app.DLpanel = uipanel(app.UIFigure);
            app.DLpanel.Title = 'DIRECTORY LOCATIONS';
            app.EVENTSEditFieldLabel = uilabel(app.DLpanel);
            app.EVENTSEditFieldLabel.Text = 'EVENTS';
            app.eventLoc = uieditfield(app.DLpanel, 'text');
            app.SESSIONEditFieldLabel = uilabel(app.DLpanel);
            app.SESSIONEditFieldLabel.Text = 'SESSION';
            app.sessLoc = uieditfield(app.DLpanel, 'text');
            app.confirm = uibutton(app.DLpanel, 'push');
            app.confirm.ButtonPushedFcn = createCallbackFcn(app, @confirmButtonPushed, true);
            app.confirm.Enable = 'off';
            app.confirm.Text = 'CONFIRM';
            app.SPpanel = uipanel(app.UIFigure);
            app.SPpanel.Enable = 'off';
            app.SPpanel.Title = 'SESSION PROCESSING';
            app.UITable = uitable(app.SPpanel);
            app.UITable.ColumnName = {'SessionID'; 'Date'; 'CSC ID'};
            app.UITable.RowName = {};
            app.findNEURO = uibutton(app.SPpanel, 'push');
            app.findNEURO.Text = 'Find Neuro file';
            app.step1 = uilabel(app.SPpanel);
            app.step1.Text = 'STEP 1:';
            app.step2 = uilabel(app.SPpanel);
            app.step2.Text = 'STEP 2:';
            app.step3 = uilabel(app.SPpanel);
            app.step3.Text = 'STEP 3:';
            app.remapSEEG = uibutton(app.SPpanel, 'push');
            app.remapSEEG.Text = 'Remap Neuro Labels';
            app.expNWB = uibutton(app.SPpanel, 'push');
            app.expNWB.Text = 'Export to NWB';
            app.seegLab = uitable(app.UIFigure);
            app.seegLab.ColumnName = {'CSC #'; 'LABEL'};
            app.seegLab.RowName = {};
            app.seegLab.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NLX_Session2Neuro_v1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end